classdef plotK2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        T2EditField          matlab.ui.control.NumericEditField
        T2EditFieldLabel     matlab.ui.control.Label
        EndEditField         matlab.ui.control.NumericEditField
        EndEditFieldLabel    matlab.ui.control.Label
        IEditField           matlab.ui.control.NumericEditField
        IEditFieldLabel      matlab.ui.control.Label
        DefaultButton        matlab.ui.control.Button
        NEditField           matlab.ui.control.NumericEditField
        NEditFieldLabel      matlab.ui.control.Label
        MEditField           matlab.ui.control.NumericEditField
        MEditFieldLabel      matlab.ui.control.Label
        StepEditField        matlab.ui.control.NumericEditField
        StepEditFieldLabel   matlab.ui.control.Label
        T1EditField          matlab.ui.control.NumericEditField
        T1EditFieldLabel     matlab.ui.control.Label
        K2EditField          matlab.ui.control.NumericEditField
        K2EditFieldLabel     matlab.ui.control.Label
        sigmaEditField       matlab.ui.control.NumericEditField
        sigmaEditFieldLabel  matlab.ui.control.Label
        rEditField           matlab.ui.control.NumericEditField
        rEditFieldLabel      matlab.ui.control.Label
        S0EditField          matlab.ui.control.NumericEditField
        S0EditFieldLabel     matlab.ui.control.Label
        K1EditField          matlab.ui.control.NumericEditField
        K1EditField_2Label   matlab.ui.control.Label
        PlotButton           matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        S0 = 100; % Description
        r = 0.05;
        sigma = 0.3;
        K1 = 10;
        K2_start = 70;
        K2_step = 1;
        K2_end = 130;
        I = 95;
        T1 = 0.5;
        T2 = 1;
        N = 1000;
        M = 100;
        optiontype = 'CoC';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            K2_list = (app.K2_start:app.K2_step:app.K2_end)';
            P_Amer_list = nan(length(K2_list), 1);
            P_Comp_list = nan(length(K2_list), 1);
            for i=1:length(K2_list)
                P_Amer_list(i) = AmericanTrinomial(app.S0, app.r, app.sigma, K2_list(i), app.I, app.T2, app.N, app.optiontype(3));
                P_Comp_list(i) = SimCompoundOptionPrice(app.S0, app.r, app.sigma, app.K1, K2_list(i), app.I, app.T1, app.T2, app.optiontype, app.M);
            end
            plot(app.UIAxes, K2_list, P_Comp_list');
            hold(app.UIAxes,'on')
            plot(app.UIAxes, K2_list, P_Amer_list');
            hold(app.UIAxes,'off')
            legend(app.UIAxes, 'Compound Green shoe', 'Green shoe');
        end

        % Value changed function: S0EditField
        function S0EditFieldValueChanged(app, event)
            value = app.S0EditField.Value;
            app.S0 = value;
        end

        % Value changed function: rEditField
        function rEditFieldValueChanged(app, event)
            value = app.rEditField.Value;
            app.r = value;
        end

        % Value changed function: sigmaEditField
        function sigmaEditFieldValueChanged(app, event)
            value = app.sigmaEditField.Value;
            app.sigma = value;
        end

        % Value changed function: K1EditField
        function K1EditFieldValueChanged(app, event)
            value = app.K1EditField.Value;
            app.K1 = value;
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            value = app.MEditField.Value;
            app.M = value;
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            app.N = value;
        end

        % Value changed function: K2EditField
        function K2EditFieldValueChanged(app, event)
            value = app.K2EditField.Value;
            app.K2_start = value;
        end

        % Value changed function: StepEditField
        function StepEditFieldValueChanged(app, event)
            value = app.StepEditField.Value;
            app.K2_step = value;
        end

        % Value changed function: T1EditField
        function T1EditFieldValueChanged(app, event)
            value = app.T1EditField.Value;
            app.T1 = value;
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.S0 = 100; % Description
            app.r = 0.05;
            app.sigma = 0.3;
            app.K1 = 10;
            app.K2_start = 70;
            app.K2_step = 1;
            app.K2_end = 130;
            app.I = 95;
            app.T1 = 0.5;
            app.T2 = 1;
            app.N = 100;
            app.M = 100;
        end

        % Value changed function: IEditField
        function IEditFieldValueChanged(app, event)
            value = app.IEditField.Value;
            app.I = value;
        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)
            value = app.EndEditField.Value;
            app.K2_end = value;
        end

        % Value changed function: T2EditField
        function T2EditFieldValueChanged(app, event)
            value = app.T2EditField.Value;
            app.T2 = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Relationship between Option price and K2')
            xlabel(app.UIAxes, 'K2')
            ylabel(app.UIAxes, 'Option Price')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.K1EditField_2Label = uilabel(app.UIFigure);
            app.K1EditField_2Label.Text = 'K1';
            app.K1EditField = uieditfield(app.UIFigure, 'numeric');
            app.K1EditField.ValueChangedFcn = createCallbackFcn(app, @K1EditFieldValueChanged, true);
            app.S0EditFieldLabel = uilabel(app.UIFigure);
            app.S0EditFieldLabel.Text = 'S0';
            app.S0EditField = uieditfield(app.UIFigure, 'numeric');
            app.S0EditField.ValueChangedFcn = createCallbackFcn(app, @S0EditFieldValueChanged, true);
            app.rEditFieldLabel = uilabel(app.UIFigure);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.UIFigure, 'numeric');
            app.rEditField.ValueChangedFcn = createCallbackFcn(app, @rEditFieldValueChanged, true);
            app.sigmaEditFieldLabel = uilabel(app.UIFigure);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @sigmaEditFieldValueChanged, true);
            app.K2EditFieldLabel = uilabel(app.UIFigure);
            app.K2EditFieldLabel.Text = 'K2';
            app.K2EditField = uieditfield(app.UIFigure, 'numeric');
            app.K2EditField.ValueChangedFcn = createCallbackFcn(app, @K2EditFieldValueChanged, true);
            app.T1EditFieldLabel = uilabel(app.UIFigure);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.UIFigure, 'numeric');
            app.T1EditField.ValueChangedFcn = createCallbackFcn(app, @T1EditFieldValueChanged, true);
            app.StepEditFieldLabel = uilabel(app.UIFigure);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepEditField.ValueChangedFcn = createCallbackFcn(app, @StepEditFieldValueChanged, true);
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.UIFigure, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.ValueChangedFcn = createCallbackFcn(app, @IEditFieldValueChanged, true);
            app.EndEditFieldLabel = uilabel(app.UIFigure);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.T2EditFieldLabel = uilabel(app.UIFigure);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.UIFigure, 'numeric');
            app.T2EditField.ValueChangedFcn = createCallbackFcn(app, @T2EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotK2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end