classdef plotT1T2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PlotButton           matlab.ui.control.Button
        K1EditField          matlab.ui.control.NumericEditField
        K1EditFieldLabel     matlab.ui.control.Label
        K1_Slider            matlab.ui.control.Slider
        K1_SliderLabel       matlab.ui.control.Label
        MEditField           matlab.ui.control.NumericEditField
        MEditFieldLabel      matlab.ui.control.Label
        T2EditField          matlab.ui.control.NumericEditField
        T2EditFieldLabel     matlab.ui.control.Label
        T1EditField          matlab.ui.control.NumericEditField
        T1EditFieldLabel     matlab.ui.control.Label
        IEditField           matlab.ui.control.NumericEditField
        IEditFieldLabel      matlab.ui.control.Label
        K2EditField          matlab.ui.control.NumericEditField
        K2EditFieldLabel     matlab.ui.control.Label
        sigmaEditField       matlab.ui.control.NumericEditField
        sigmaEditFieldLabel  matlab.ui.control.Label
        rEditField           matlab.ui.control.NumericEditField
        rEditFieldLabel      matlab.ui.control.Label
        S0EditField          matlab.ui.control.NumericEditField
        S0EditFieldLabel     matlab.ui.control.Label
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
    end

    
    properties (Access = private)
        % Description
        S0 = 100;
        r = 0.05;
        sigma = 0.3;
        K1 = 24;
        K2 = 100;
        I = 95;
        T1 = 0.5;
        T2 = 1;
        M = 100;
        N = 100
        T1_fig1;
        T2_fig2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: S0EditField
        function S0EditFieldValueChanged2(app, event)
            value = app.S0EditField.Value;
            app.S0 = value;
        end

        % Value changed function: rEditField
        function rEditFieldValueChanged(app, event)
            value = app.rEditField.Value;
            app.r = value;
        end

        % Value changed function: sigmaEditField
        function sigmaEditFieldValueChanged(app, event)
            value = app.sigmaEditField.Value;
            app.sigma = value;
        end

        % Value changed function: K2EditField
        function K2EditFieldValueChanged(app, event)
            value = app.K2EditField.Value;
            app.K2 = value;
        end

        % Value changed function: IEditField
        function IEditFieldValueChanged(app, event)
            value = app.IEditField.Value;
            app.I = value;
        end

        % Value changed function: T1EditField
        function T1EditFieldValueChanged(app, event)
            value = app.T1EditField.Value;
            app.T1 = value;
        end

        % Value changed function: T2EditField
        function T2EditFieldValueChanged(app, event)
            value = app.T2EditField.Value;
            app.T2 = value;
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            value = app.MEditField.Value;
            app.M = value;
        end

        % Value changing function: K1_Slider
        function K1_SliderValueChanging(app, event)
            changingValue = event.Value;
            app.K1 = changingValue;
        end

        % Value changed function: K1EditField
        function K1EditFieldValueChanged(app, event)
            value = app.K1EditField.Value;
            app.K1 = value;
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            P = nan(length(app.T1), 1);
            OP =  nan(length(app.T1), 1);
            P_Amer = AmericanTrinomial(app.S0, app.r, app.sigma, app.K2, app.I, app.T2, app.N, 'C');
            PA = nan(length(app.T1), 1);
            PA(:) = P_Amer;
            
            for i=1:length(app.T1)
                [P(i), ~, ~, OP(i)] = SimCompoundOptionPrice(app.S0, app.r, app.sigma, app.K1, app.K2, app.I, app.T1(i), app.T2, 'CoC', app.M);
                P(i) = P(i)+app.K1*exp(-app.r*app.T1(i));
            end
            plot(app.UIAxes, app.T1, P);
            hold(app.UIAxes, "on")
            plot(app.UIAxes, app.T1, PA);
            hold(app.UIAxes, "off")
            legend(app.UIAxes, 'Compound Green Shoe', 'Greeen Shoe');
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.T1_fig1 = (0.1:0.05:app.T2-0.05)';
            app.T2_fig2 = (0.1:0.1:2*app.T2-0.1)';
            P_fig1 = nan(length(app.T1_fig1), 1);
            P_fig2 = nan(length(app.T2_fig2), 1);
            Px_fig1 = nan(length(app.T1_fig1), 1);
            OP =  nan(length(app.T1_fig1), 1);
            P_Amer = AmericanTrinomial(app.S0, app.r, app.sigma, app.K2, app.I, app.T2, app.N, 'C');
            PA = nan(length(app.T1_fig1), 1);
            PA(:) = P_Amer;

            PA_fig2 = nan(length(app.T2_fig2), 1);
            
            for i=1:length(app.T1_fig1)
                [P_fig1(i), ~, ~, OP(i)] = SimCompoundOptionPrice(app.S0, app.r, app.sigma, app.K1, app.K2, app.I, app.T1_fig1(i), app.T2, 'CoC', app.M);
                [P_fig2(i), ~, ~, OP(i)] = SimCompoundOptionPrice(app.S0, app.r, app.sigma, app.K1, app.K2, app.I, app.T2_fig2(i)/2, app.T2_fig2(i), 'CoC', app.M);
                Px_fig1(i) = P_fig1(i)+min(app.K1, OP(i))*exp(-app.r*app.T1_fig1(i));
                PA_fig2(i) = AmericanTrinomial(app.S0, app.r, app.sigma, app.K2, app.I, app.T2_fig2(i), app.N, 'C');
            end
            plot(app.UIAxes, app.T1_fig1, P_fig1);
            hold(app.UIAxes, "on")
            plot(app.UIAxes, app.T1_fig1, PA);
            plot(app.UIAxes, app.T1_fig1, Px_fig1);
            plot(app.UIAxes, app.T1_fig1, OP);
            hold(app.UIAxes, "off")
            legend(app.UIAxes, 'Compound Green shoe', 'Green shoe', 'Sum compound', 'Underlying Green shoe');

            plot(app.UIAxes2, app.T2_fig2, P_fig2);
            hold(app.UIAxes2, "on")
            plot(app.UIAxes2, app.T2_fig2, PA_fig2);
            hold(app.UIAxes2, "off")
            legend(app.UIAxes2, 'Compound Green shoe', 'Green shoe');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Relationship between Option price and T1')
            xlabel(app.UIAxes, 'T1')
            ylabel(app.UIAxes, 'Option price')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Relationship between Option price and T2')
            xlabel(app.UIAxes2, 'T2')
            ylabel(app.UIAxes2, 'Option price')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.S0EditFieldLabel = uilabel(app.UIFigure);
            app.S0EditFieldLabel.Text = 'S0';
            app.S0EditField = uieditfield(app.UIFigure, 'numeric');
            app.S0EditField.ValueChangedFcn = createCallbackFcn(app, @S0EditFieldValueChanged2, true);
            app.S0EditField.Value = 100;
            app.rEditFieldLabel = uilabel(app.UIFigure);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.UIFigure, 'numeric');
            app.rEditField.ValueChangedFcn = createCallbackFcn(app, @rEditFieldValueChanged, true);
            app.rEditField.Value = 0.05;
            app.sigmaEditFieldLabel = uilabel(app.UIFigure);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @sigmaEditFieldValueChanged, true);
            app.sigmaEditField.Value = 0.3;
            app.K2EditFieldLabel = uilabel(app.UIFigure);
            app.K2EditFieldLabel.Text = 'K2';
            app.K2EditField = uieditfield(app.UIFigure, 'numeric');
            app.K2EditField.ValueChangedFcn = createCallbackFcn(app, @K2EditFieldValueChanged, true);
            app.K2EditField.Value = 100;
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.ValueChangedFcn = createCallbackFcn(app, @IEditFieldValueChanged, true);
            app.IEditField.Value = 95;
            app.T1EditFieldLabel = uilabel(app.UIFigure);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.UIFigure, 'numeric');
            app.T1EditField.ValueChangedFcn = createCallbackFcn(app, @T1EditFieldValueChanged, true);
            app.T1EditField.Value = 0.5;
            app.T2EditFieldLabel = uilabel(app.UIFigure);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.UIFigure, 'numeric');
            app.T2EditField.ValueChangedFcn = createCallbackFcn(app, @T2EditFieldValueChanged, true);
            app.T2EditField.Value = 2;
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.UIFigure, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.MEditField.Value = 100;
            app.K1_SliderLabel = uilabel(app.UIFigure);
            app.K1_SliderLabel.Text = 'K1_Slider';
            app.K1_Slider = uislider(app.UIFigure);
            app.K1_Slider.ValueChangingFcn = createCallbackFcn(app, @K1_SliderValueChanging, true);
            app.K1EditFieldLabel = uilabel(app.UIFigure);
            app.K1EditFieldLabel.Text = 'K1';
            app.K1EditField = uieditfield(app.UIFigure, 'numeric');
            app.K1EditField.ValueChangedFcn = createCallbackFcn(app, @K1EditFieldValueChanged, true);
            app.K1EditField.Value = 10;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotT1T2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end